classdef MenuDetectaVortices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DetectButton          matlab.ui.control.Button
        ThresholdSliderLabel  matlab.ui.control.Label
        ThresholdSlider       matlab.ui.control.Slider
        AreaSliderLabel       matlab.ui.control.Label
        AreaSlider            matlab.ui.control.Slider
        InvertCheckBox        matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Value changing function: AreaSlider
        function AreaSliderValueChanging(app, event)
            changingValue = event.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, changingValue, app.ThresholdSlider.Value,...
                                        app.InvertCheckBox.Value);
            fig.UserData.centrosc = centroids; 
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, app.AreaSlider.Value, changingValue,...
                                        app.InvertCheckBox.Value)
            
            fig.UserData.centrosc = centroids
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            value = app.InvertCheckBox.Value;
            fig = findobj('Name', 'Image');
            
            centroids = detectCentros(fig.UserData.Map, app.AreaSlider.Value, app.ThresholdSlider.Value,...
                                        value);
            fig.UserData.centrosc = centroids;                        
            
            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            fig = findobj('Name', 'Image');
            centroids = detectCentros(fig.UserData.Map, app.AreaSlider.Value, app.ThresholdSlider.Value,...
                                        app.InvertCheckBox.Value)
           fig.UserData.centrosc = centroids;
           fig.UserData.trian = delaunay(centroids);
            close(app.UIFigure)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.5;
            app.AreaSliderLabel = uilabel(app.UIFigure);
            app.AreaSliderLabel.Text = 'Area ';
            app.AreaSlider = uislider(app.UIFigure);
            app.AreaSlider.ValueChangingFcn = createCallbackFcn(app, @AreaSliderValueChanging, true);
            app.AreaSlider.Value = 50;
            app.InvertCheckBox = uicheckbox(app.UIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MenuDetectaVortices

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end